classdef Application < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        BuildingOptimization        matlab.ui.Figure
        tgMain                      matlab.ui.container.TabGroup
        tabOptimization             matlab.ui.container.Tab
        pnlInput                    matlab.ui.container.Panel
        LimitesinferioresEditFieldLabel  matlab.ui.control.Label
        eLowerLimits                matlab.ui.control.EditField
        LimitessuperioresEditFieldLabel  matlab.ui.control.Label
        eUpperLimits                matlab.ui.control.EditField
        VariveisEditFieldLabel      matlab.ui.control.Label
        eNumVars                    matlab.ui.control.NumericEditField
        IteraesLabel                matlab.ui.control.Label
        eNumIters                   matlab.ui.control.NumericEditField
        PopulaoLabel                matlab.ui.control.Label
        eNumPop                     matlab.ui.control.NumericEditField
        ArquivoedificaoidfLabel     matlab.ui.control.Label
        eEdificationPath            matlab.ui.control.EditField
        btnEdificationSearch        matlab.ui.control.Button
        ArquivoalteraotxtLabel      matlab.ui.control.Label
        eModificationPath           matlab.ui.control.EditField
        btnModificationSearch       matlab.ui.control.Button
        FunodemapeamentoLabel       matlab.ui.control.Label
        eMappingFunction            matlab.ui.control.EditField
        tgAlgorithms                matlab.ui.container.TabGroup
        tabPlaceholder              matlab.ui.container.Tab
        pnlOutput                   matlab.ui.container.Panel
        tgOutput                    matlab.ui.container.TabGroup
        tabConsumption              matlab.ui.container.Tab
        lampConsumption             matlab.ui.control.Lamp
        ConsiderarobjetivoLabel_3   matlab.ui.control.Label
        switchConsumption           matlab.ui.control.Switch
        PesoEditFieldLabel          matlab.ui.control.Label
        eConsumptionWeight          matlab.ui.control.NumericEditField
        ValorbaseEditFieldLabel     matlab.ui.control.Label
        eConsumptionBase            matlab.ui.control.NumericEditField
        DeslocamentoLabel           matlab.ui.control.Label
        eConsumptionOffset          matlab.ui.control.NumericEditField
        tabComfort                  matlab.ui.container.Tab
        lampComfort                 matlab.ui.control.Lamp
        ConsiderarobjetivoLabel_2   matlab.ui.control.Label
        switchComfort               matlab.ui.control.Switch
        ValorbaseEditField_2Label   matlab.ui.control.Label
        eComfortBase                matlab.ui.control.NumericEditField
        PesoEditField_2Label        matlab.ui.control.Label
        eComfortWeight              matlab.ui.control.NumericEditField
        DeslocamentoLabel_2         matlab.ui.control.Label
        eComfortOffset              matlab.ui.control.NumericEditField
        tabCustom                   matlab.ui.container.Tab
        ConsiderarobjetivoLabel     matlab.ui.control.Label
        switchCustom                matlab.ui.control.Switch
        lampCustom                  matlab.ui.control.Lamp
        ValorbaseEditField_3Label   matlab.ui.control.Label
        eCustomBase                 matlab.ui.control.NumericEditField
        PesoEditField_3Label        matlab.ui.control.Label
        eCustomWeight               matlab.ui.control.NumericEditField
        FunoobjetivaEditFieldLabel  matlab.ui.control.Label
        eCustomFunction             matlab.ui.control.EditField
        DeslocamentoLabel_3         matlab.ui.control.Label
        eCustomOffset               matlab.ui.control.NumericEditField
        btnOptimize                 matlab.ui.control.Button
        lampCustomMini              matlab.ui.control.Lamp
        lampComfortMini             matlab.ui.control.Lamp
        lampConsumptionMini         matlab.ui.control.Lamp
        tabConfiguration            matlab.ui.container.Tab
        CaminhosPanel               matlab.ui.container.Panel
        btnDomusPathSearch          matlab.ui.control.Button
        DiretrioDomusLabel          matlab.ui.control.Label
        eDomusPath                  matlab.ui.control.EditField
        pnlAlgorithmRegister        matlab.ui.container.Panel
        listAlgorithms              matlab.ui.control.ListBox
        eAlgorithm                  matlab.ui.control.EditField
        btnAddAlgorithm             matlab.ui.control.Button
        btnRemoveAlgorithm          matlab.ui.control.Button
        pnlUtils                    matlab.ui.container.Panel
        btnModelsMtr                matlab.ui.control.Button
        btnIndexMtr                 matlab.ui.control.Button
        VarivelLabel                matlab.ui.control.Label
        eModelsMtr                  matlab.ui.control.EditField
        VarivelLabel_2              matlab.ui.control.Label
        eIndexMtr                   matlab.ui.control.EditField
        tabResults                  matlab.ui.container.Tab
        pnlGraph                    matlab.ui.container.Panel
        graphResult                 matlab.ui.control.UIAxes
        pnlOutputControl            matlab.ui.container.Panel
        btnStop                     matlab.ui.control.StateButton
        pnlOutputGraphs             matlab.ui.container.Panel
        btnConsumptionGraph         matlab.ui.control.StateButton
        btnComfortGraph             matlab.ui.control.StateButton
        btnCustomGraph              matlab.ui.control.StateButton
        btnTotalCostGraph           matlab.ui.control.StateButton
        VarivelLabel_3              matlab.ui.control.Label
        eModelsMtr_2                matlab.ui.control.EditField
        btnModelsMtr_2              matlab.ui.control.Button
        pnlStatistics               matlab.ui.container.Panel
        IteraesrealizadasLabel      matlab.ui.control.Label
        eIterationsPerformed        matlab.ui.control.NumericEditField
        AvaliaesdafunocustoLabel    matlab.ui.control.Label
        eCostEvaluations            matlab.ui.control.NumericEditField
    end


    properties (Access = public)
        % General optimization arguments
        savedGeneralArguments = [];
        
        % Algorithm-Specific optimization arguments
        savedSpecificArguments = [];
        
        % Best solutions per iteration
        bestPerIteration = [];
        
        % All solutions evaluated during the optimization
        solutions = [];
    end
    
    methods (Access = public)

        function [algorithm, result] = validateAlgorithm(app, algorithmClassName)
            try
                algorithmClass = meta.class.fromName(algorithmClassName);
                if numel(algorithmClass) == 0
                    error('A classe do algoritmo especificado não existe!');
                end
                
                algorithmClass = algorithmClass(1);
                if algorithmClass.Abstract
                    error('A classe do algoritmo especificado é abstrata. É necessaria uma implementação concreta!');
                end
                
                if ~any(strcmp(superclasses(algorithmClass.Name), 'AlgorithmModule'))
                    error('A classe do algoritmo especificado deve ser uma sub-classe de ''AlgorithmModule''!');
                end
                
                algorithm = eval(algorithmClassName);
                result = [];
            catch exception
                algorithm = [];
                result = exception;
            end
        end
        
        function [] = saveConfiguration(app)
            domusPath = app.eDomusPath.Value;
            algorithms = app.listAlgorithms.Items;
            lastEdificationPath = app.eEdificationPath.Value;
            lastModificationPath = app.eModificationPath.Value;
            save('config.dat', 'domusPath', 'algorithms', 'lastEdificationPath', 'lastModificationPath');
        end
        
        function [result] = loadConfiguration(app)
            try
                config = load('config.dat', '-mat');
                
                if isfield(config, 'domusPath')
                    app.eDomusPath.Value = config.domusPath;
                end
                
                if isfield(config, 'algorithms')
                    for algorithmName = config.algorithms
                        [subResult] = app.addAlgorithm(char(algorithmName));
                        if isa(subResult, 'MException')
                            result = subResult;
                            return;
                        end
                    end
                end
                
                if isfield(config, 'lastEdificationPath')
                    app.eEdificationPath.Value = config.lastEdificationPath;
                end
                
                if isfield(config, 'lastModificationPath')
                    app.eModificationPath.Value = config.lastModificationPath;
                end
                result = [];
            catch exception
                result = exception;
            end
        end
        
        function [result] = buildAlgorithmComponents(app, algorithm)
            algorithmTab = uitab(app.tgAlgorithms, 'Title', sprintf('%s (%s)', algorithm.name, algorithm.prefix));
            algorithmTab.UserData = algorithm;
            try
                [components] = algorithm.registerComponents(algorithmTab);
                spacing = 10;
                ceiling = algorithmTab.Position(4) - spacing;
                
                textValueRatio = 0.25;
                textSpace = ceil(algorithmTab.Position(3) * textValueRatio);
                %valueSpace = ceil(algorithmTab.Position(3) * (1 - textValueRatio));
                for i = 1:size(components, 1)
                    textComponent = components(i, 1);
                    textSize = textComponent.Position(1,4);
                    valueComponent = components(i, 2);
                    valueSize = valueComponent.Position(1,4);
                    maxSize = max(textSize, valueSize);
                    baseline = ceiling - ceil(maxSize / 2);
                    textComponent.Position = [1, baseline - ceil(textSize / 2), textSpace, textSize];
                    valueComponent.Position(1:2) = [textSpace + spacing, baseline - ceil(valueSize / 2)];
                    ceiling = ceiling - maxSize - spacing;
                end
                result = [];
            catch exception
                algorithmTab.Parent = [];
                result = exception;
            end
        end

        function [result] = addAlgorithm(app, algorithmName)
            try
                if isempty(algorithmName)
                    error('Nome inválido para o algoritmo!');
                end
                
                if any(strcmp(app.listAlgorithms.Items, algorithmName))
                    error('O algoritmo especificado já existe!');
                end
                
                [algorithm, result] = app.validateAlgorithm(algorithmName);
                if isa(result, 'MException')
                    error('prog:input', 'Erro durante o carregamento do algoritmo: %s', result.message);
                end
                
                nAlgorithms = numel(app.listAlgorithms.Items);
                app.listAlgorithms.Items(nAlgorithms + 1) = {algorithmName};
                
                [result] = app.buildAlgorithmComponents(algorithm);
                if isa(result, 'MException')
                    app.listAlgorithms.Items(nAlgorithms + 1) = [];
                    rethrow(result);
                end
                
                app.btnOptimize.Enable = 'on';
            catch exception
                result = exception;
            end
        end
        
        function [result] = removeAlgorithm(app, algorithmName)
            try
                if isempty(algorithmName)
                    error('Nome inválido para o algoritmo!');
                end
                
                if ~any(strcmp(app.listAlgorithms.Items, algorithmName))
                    error('O algoritmo especificado não existe!');
                end
                
                nAlgorithms = strcmp(app.listAlgorithms.Items, algorithmName);
                app.listAlgorithms.Items(nAlgorithms) = [];
                app.tgAlgorithms.Children(nAlgorithms).Parent = [];
                
                if numel(app.listAlgorithms.Value) == 0
                    app.btnOptimize.Enable = 'off';
                end
                result = [];
            catch exception
                result = exception;
            end
        end
        
        function [generalArguments, result] = validateGeneralArguments(app)
            try
                generalArguments.numVars = app.eNumVars.Value;
                generalArguments.numIters = app.eNumIters.Value;
                generalArguments.numPop = app.eNumPop.Value;
                
                lowerLimits = eval(app.eLowerLimits.Value);
                if ~isvector(lowerLimits)
                    error('Os limites inferiores devem ser representados por um vetor!');
                end
                if ~isa(lowerLimits, 'numeric')
                    error('O vetor de limites inferiores deve conter apenas números!');
                end
                if numel(lowerLimits) ~= generalArguments.numVars
                    error('O número de elementos do vetor de limites inferiores deve ser o mesmo do número de variáveis!');
                end
                generalArguments.lowerLimits = lowerLimits;
                
                upperLimits = eval(app.eUpperLimits.Value);
                if ~isvector(upperLimits)
                    error('Os limites superiores devem ser representados por um vetor!');
                end
                if ~isa(upperLimits, 'numeric')
                    error('O vetor de limites superiores deve conter apenas números!');
                end
                if numel(upperLimits) ~= generalArguments.numVars
                    error('O número de elementos do vetor de limites superiores deve ser o mesmo do número de variáveis!');
                end
                generalArguments.upperLimits = upperLimits;
                
                if any(generalArguments.upperLimits < generalArguments.lowerLimits)
                    error('Todos os limites inferiores devem ser menores ou iguais ao limites superiores!');
                end
                
                mappingFunction = eval(app.eMappingFunction.Value);
                if ~isa(mappingFunction, 'function_handle')
                    error('A função de mapeamento deve ser representada por uma função!');
                end
                generalArguments.mappingFunction = mappingFunction;
                
                generalArguments.progressFunction = @(iteration, bestX, bestY) app.receiveProgressReport(iteration, bestX, bestY);
                
                edificationPath = app.eEdificationPath.Value;
                if isempty(edificationPath)
                    error('O arquivo da edificação deve ser especificado!');
                end
                generalArguments.edificationPath = edificationPath;
                
                edificationPathComponents = strsplit(edificationPath, filesep());
                edificationFile = char(edificationPathComponents(end));
                edificationFolder = strjoin(edificationPathComponents(1:end-1), filesep());
                simulationFolder = strcat(edificationFolder, filesep(), '#', edificationFile(1:end-4), filesep(), 'saidas');
                pixelCountFolder = strcat(simulationFolder, filesep(), 'lastPixelcount');
                
                generalArguments.edificationFile = edificationFile;
                generalArguments.edificationFolder = edificationFolder;
                generalArguments.simulationFolder = simulationFolder;
                generalArguments.pixelCountFolder = pixelCountFolder;
                
                modificationPath = app.eModificationPath.Value;
                if isempty(modificationPath)
                    error('O arquivo de alteração deve ser especificado!');
                end
                generalArguments.modificationPath = modificationPath;
                
                modificationPathComponents = strsplit(modificationPath, filesep());
                generalArguments.modificationFile = char(modificationPathComponents(end));
                generalArguments.modificationFolder = strjoin(modificationPathComponents(1:end-1), filesep());
                
                if strcmp(app.switchConsumption.Value, 'Sim')
                    consumptionBase = app.eConsumptionBase.Value;
                    if consumptionBase <= 0
                        error('Especifique um valor maior que 0 para a base de consumo!');
                    end
                    generalArguments.consumptionBase = consumptionBase;
                    
                    generalArguments.consumptionOffset = app.eConsumptionOffset.Value;
                    
                    consumptionWeight = app.eConsumptionWeight.Value;
                    if consumptionWeight <= 0
                        error('Especifique um valor maior que 0 para o peso de consumo!');
                    end
                    generalArguments.consumptionWeight = consumptionWeight;
                    
                    optimizeConsumption = true;
                else
                    optimizeConsumption = false;
                end
                generalArguments.optimizeConsumption = optimizeConsumption;
                
                if strcmp(app.switchComfort.Value, 'Sim')
                    comfortBase = app.eComfortBase.Value;
                    if comfortBase <= 0
                        error('Especifique um valor maior que 0 para a base de conforto!');
                    end
                    generalArguments.comfortBase = comfortBase;
                    
                    generalArguments.comfortOffset = app.eComfortOffset.Value;
                    
                    comfortWeight = app.eComfortWeight.Value;
                    if comfortWeight <= 0
                        error('Especifique um valor maior que 0 para o peso de conforto!');
                    end
                    generalArguments.comfortWeight = comfortWeight;
                    
                    optimizeComfort = true;
                else
                    optimizeComfort = false;
                end
                generalArguments.optimizeComfort = optimizeComfort;
                
                if strcmp(app.switchCustom.Value, 'Sim')
                    customFunction = eval(app.eCustomFunction.Value);
                    if ~isa(customFunction, 'function_handle')
                        error('A função objetiva customizável deve ser representada por uma função!');
                    end
                    generalArguments.customFunction = customFunction;
                    
                    generalArguments.customOffset = app.eCustomOffset.Value;
                    
                    customBase = app.eCustomBase.Value;
                    if customBase <= 0
                        error('Especifique um valor maior que 0 para a base do objetivo customizável!');
                    end
                    generalArguments.customBase = customBase;
                    
                    customWeight = app.eCustomWeight.Value;
                    if customWeight <= 0
                        error('Especifique um valor maior que 0 para o peso do objetivo customizável!');
                    end
                    generalArguments.customWeight = customWeight;
                    
                    optimizeCustom = true;
                else
                    optimizeCustom = false;
                end
                generalArguments.optimizeCustom = optimizeCustom;
                
                if ~optimizeConsumption && ~optimizeComfort && ~optimizeCustom
                    error('É necessario selecionar pelo menos um objetivo para a otimização!');
                end
                
                result = [];
            catch exception
                generalArguments = [];
                result = exception;
            end
        end
        
        function [cost] = evaluateCostFunction(app, input)
            fprintf('Raw input: %s\n', num2str(input));
            try
                mappedInput = app.savedGeneralArguments.mappingFunction(input);
            catch exception
                error('prog:input', 'Erro durante a chamada da função de mapeamento: %s', exception.message);
            end
            
            [lastSimulation] = app.getLastResultsFolder();
            [result] = app.runSimulation(mappedInput);
            if isa(result, 'MException')
                error('prog:input', 'Erro durante o processo de simulação: %s', result.message);
            end
            
            [currentSimulation] = app.getLastResultsFolder();
            if lastSimulation == currentSimulation
                error('prog:input', 'A simulação não gerou resultados! Ultima simulação: sim%03d', currentSimulation);
            end
            
            resultsFolder = sprintf('%s%ssim%03d', app.savedGeneralArguments.simulationFolder, filesep(), currentSimulation);
            finishedFlagFile = 'terminou.txt';
            finishedFlagPath = strcat(resultsFolder, filesep(), finishedFlagFile);
            if exist(finishedFlagPath, 'file') ~= 2
                error('A simulação não foi finalizada com sucesso');
            end
            
            totalCost = 0;
            totalBase = 0;
            solution.input = input;
            solution.mappedInput = mappedInput;
            if app.savedGeneralArguments.optimizeConsumption
                [result, consumptionCost] = app.evaluateConsumptionObjective(resultsFolder);
                if isa(result, 'MException')
                    error('prog:input', 'Erro durante a análise dos resultados de consumo: %s', result.message);
                end
                
                consumptionWeight = app.savedGeneralArguments.consumptionWeight;
                consumptionOffset = app.savedGeneralArguments.consumptionOffset;
                consumptionBase = app.savedGeneralArguments.consumptionBase;
                normalizedConsumptionCost = consumptionWeight * (consumptionCost / consumptionBase + consumptionOffset);
                
                totalCost = totalCost + normalizedConsumptionCost;
                totalBase = totalBase + 1;
                
                solution.consumptionCost = consumptionCost;
                solution.normalizedConsumptionCost = normalizedConsumptionCost;
            end
            
            if app.savedGeneralArguments.optimizeComfort
                [result, comfortCost] = app.evaluateComfortObjective(resultsFolder);
                if isa(result, 'MException')
                    error('prog:input', 'Erro durante a análise dos resultados de conforto: %s', result.message);
                end
                
                comfortWeight = app.savedGeneralArguments.comfortWeight;
                comfortOffset = app.savedGeneralArguments.comfortOffset;
                comfortBase = app.savedGeneralArguments.comfortBase;
                normalizedComfortCost = comfortWeight * (comfortCost / comfortBase + comfortOffset);
                
                totalCost = totalCost + normalizedComfortCost;
                totalBase = totalBase + 1;
                
                solution.comfortCost = comfortCost;
                solution.normalizedComfortCost = normalizedComfortCost;
            end
            
            if app.savedGeneralArguments.optimizeCustom
                [result, customCost] = app.evaluateCustomObjective(input);
                if isa(result, 'MException')
                    error('prog:input', 'Erro durante a análise dos resultados do objetivo customizável: %s', result.message);
                end
                
                customWeight = app.savedGeneralArguments.customWeight;
                customOffset = app.savedGeneralArguments.customOffset;
                customBase = app.savedGeneralArguments.customBase;
                normalizedCustomCost = customWeight * (customCost / customBase + customOffset);
                
                totalCost = totalCost + normalizedCustomCost;
                totalBase = totalBase + 1;
                
                solution.customCost = customCost;
                solution.normalizedCustomCost = normalizedCustomCost;
            end
            
            normalizedTotalCost = totalCost / totalBase;
            cost = normalizedTotalCost;
            
            solution.totalCost = totalCost;
            solution.normalizedTotalCost = normalizedTotalCost;
            
            app.solutions = [app.solutions solution];
            
            app.eCostEvaluations.Value = app.eCostEvaluations.Value + 1;
            
            oldStates = [app.btnConsumptionGraph.Value, app.btnComfortGraph.Value, app.btnCustomGraph.Value, app.btnTotalCostGraph.Value];
            app.executePendingTasks();
            newStates = [app.btnConsumptionGraph.Value, app.btnComfortGraph.Value, app.btnCustomGraph.Value, app.btnTotalCostGraph.Value];
            
            if any(oldStates ~= newStates)
                app.updateResultGraph();
                app.executePendingTasks();
            end
        end
        
        function [result, consumptionCost] = evaluateConsumptionObjective(app, resultsFolder)
            try
                consumptionFile = 'Zon1CONS.txt';
                consumptionPath = strcat(resultsFolder, filesep(), consumptionFile);
                
                if exist(consumptionPath, 'file') ~= 2
                    error('prog:input', 'O arquivo de resultados de consumo ''%s'' não foi encontrado', consumptionPath);
                end
                
                consumptionText = fileread(consumptionPath);
                consumptionLine = strsplit(consumptionText, "\r\n");
                
                result = [];
                
                consumptionCost = sscanf(char(consumptionLine(4)), 'Total = %f KWh');
            catch exception
                result = exception;
                consumptionCost = [];
            end
        end
        
        function [result, comfortCost] = evaluateComfortObjective(app, resultsFolder)
            try
                comfortFile = 'Zon1TUP_PMV.txt';
                comfortPath = strcat(resultsFolder, filesep(), comfortFile);

                if exist(comfortPath, 'file') ~= 2
                    error('prog:input', 'O arquivo de resultados de conforto ''%s'' não foi encontrado', comfortPath);
                end
                
                comfortText = fileread(comfortPath);
                comfortLines = strsplit(comfortText, "\r\n");
                comfortLines = comfortLines(4:end - 1);
                
                comfortSize = numel(comfortLines);
                comfortMean = 0;
                for n = 1:comfortSize
                    comfortValues = sscanf(char(comfortLines(n)), '%f');
                    comfortMean = comfortMean + abs(comfortValues(1));
                end
                result = [];
                
                comfortCost = comfortMean / comfortSize;
            catch exception
                result = exception;
                comfortCost = [];
            end
        end
        
        function [result, customCost] = evaluateCustomObjective(app, input)
            try
                customFunction = app.savedGeneralArguments.customFunction;
                
                result = [];
                
                customCost = customFunction(input);
            catch exception
                result = exception;
                customCost = [];
            end
        end
        
        function [result] = runSimulation(app, mappedValues)
            try
                if exist(app.savedGeneralArguments.pixelCountFolder, 'dir')
                    [status, msg, ~] = rmdir(app.savedGeneralArguments.pixelCountFolder, 's');
                    if ~status
                        error('prog:input', 'Falha ao remover diretório ''lastPixelcount'': %s', msg);
                    end
                end
                
                modificationBaseText = fileread(app.savedGeneralArguments.modificationPath);
                
                for i = 1:numel(mappedValues)
                    modificationBaseText = replace(modificationBaseText, strcat('{', num2str(i), '}'), num2str(mappedValues{i}));
                end
                
                modificationFolder = app.savedGeneralArguments.modificationFolder;
                modificationFile = app.savedGeneralArguments.modificationFile;
                modificationPath = strcat(modificationFolder, filesep, modificationFile(1:end-4), 'Simulation.txt');
                
                fid = fopen(modificationPath,'w');
                if fid == -1
                    error('Falha durante a atualização do arquivo de modificação!');
                end
                fprintf(fid, '%s', modificationBaseText);
                fclose(fid);
                
                edificationPath = app.savedGeneralArguments.edificationPath;
                
                [~, message] = dos(sprintf('DomusConsole.exe -m "%s" "%s"', edificationPath, modificationPath));
                if contains(message, '[erro]')
                    error('Erro durante a substituição de parâmetros no arquivo de edificação!');
                end
                
                [~, message] = dos(sprintf('DomusConsole.exe -q -txt "%s"', edificationPath));
                if contains(message, '[erro]')
                    error('Erro durante a execução da simulação da edificação!');
                end
                result = [];
            catch exception
                result = exception;
            end
        end
        
        function toggleAlgorithmDependentFields(app, state)
            app.eNumVars.Enable = state;
            app.eNumIters.Enable = state;
            app.eNumPop.Enable = state;
            app.eLowerLimits.Enable = state;
            app.eUpperLimits.Enable = state;
            app.eEdificationPath.Enable = state;
            app.btnEdificationSearch.Enable = state;
            app.eModificationPath.Enable = state;
            app.btnModificationSearch.Enable = state;
            app.eMappingFunction.Enable = state;
            app.btnOptimize.Enable = state;
            
            for tab = app.tgAlgorithms.Children'
                for component = tab.Children'
                    if isprop(component, 'Enable')
                        component.Enable = state;
                    end
                end
            end
            
            for tab = app.tgOutput.Children'
                for component = tab.Children'
                    if isprop(component, 'Enable')
                        component.Enable = state;
                    end
                end
            end
            
            app.eDomusPath.Enable = state;
            app.btnDomusPathSearch.Enable = state;
            app.listAlgorithms.Enable = state;
            app.eAlgorithm.Enable = state;
            app.eModelsMtr.Enable = state;
            app.btnModelsMtr.Enable = state;
            app.eIndexMtr.Enable = state;
            app.btnIndexMtr.Enable = state;
            
            if strcmp(state, 'on')
                app.btnStop.Enable = 'off';
                app.btnConsumptionGraph.Enable = 'off';
                app.btnComfortGraph.Enable = 'off';
                app.btnCustomGraph.Enable = 'off';
                app.btnTotalCostGraph.Enable = 'off';
            else
                app.btnStop.Enable = 'on';
                if app.savedGeneralArguments.optimizeConsumption
                    app.btnConsumptionGraph.Enable = 'on';
                else
                    app.btnConsumptionGraph.Enable = 'off';
                end
                
                if app.savedGeneralArguments.optimizeComfort
                    app.btnComfortGraph.Enable = 'on';
                else
                    app.btnComfortGraph.Enable = 'off';
                end
                
                if app.savedGeneralArguments.optimizeCustom
                    app.btnCustomGraph.Enable = 'on';
                else
                    app.btnCustomGraph.Enable = 'off';
                end
                
                app.btnTotalCostGraph.Enable = 'on';
            end
            app.btnStop.Value = 0;
            app.btnConsumptionGraph.Value = 0;
            app.btnComfortGraph.Value = 0;
            app.btnCustomGraph.Value = 0;
            app.btnTotalCostGraph.Value = 1;
        end
        
        function [stop] = receiveProgressReport(app, iteration, bestX, bestY)
            savedSolutions = app.solutions(cellfun(@(x)all(x == bestX), {app.solutions.input}));
            if numel(savedSolutions) < 1
                error('Inconsistências no melhor resultado da iteração');
            end

            app.bestPerIteration = [app.bestPerIteration, savedSolutions(1)];
            app.eIterationsPerformed.Value = app.eIterationsPerformed.Value + 1;
            
            app.executePendingTasks();

            app.updateResultGraph();
            app.executePendingTasks();
            
            if app.btnStop.Value
                stop = true;
            else
                stop = false;
            end
        end
        
        function [lastFolder] = getLastResultsFolder(app)
            resultFolders = dir(app.savedGeneralArguments.simulationFolder);
            
            resultFolders(~[resultFolders.isdir]) = [];
            
            folderNames = {resultFolders.name};
            
            folderNames(cellfun(@numel, regexp(folderNames, 'sim\d{3}')) ~= 1) = [];
            
            folderNames = replace(folderNames, 'sim', '');
            
            folderNumbers = cellfun(@str2double, folderNames);
            
            lastFolder = max(folderNumbers);
        end
        
        function updateResultGraph(app)
            if numel(app.bestPerIteration) < 1
                cla(app.graphResult);
                legend(app.graphResult, 'hide');
                firstBest = 1;
                lastBest = 0;
            else
                allCosts = [];
                legends = {};
                if app.savedGeneralArguments.optimizeConsumption && app.btnConsumptionGraph.Value
                    consumptionCosts = [app.bestPerIteration.normalizedConsumptionCost];
                    allCosts = [allCosts, consumptionCosts];
                    legends = [legends, {'Consumo'}];
                end
                if app.savedGeneralArguments.optimizeComfort && app.btnComfortGraph.Value
                    comfortCosts = [app.bestPerIteration.normalizedComfortCost];
                    allCosts = [allCosts, comfortCosts];
                    legends = [legends, {'Conforto'}];
                end
                if app.savedGeneralArguments.optimizeCustom && app.btnCustomGraph.Value
                    customCosts = [app.bestPerIteration.normalizedCustomCost];
                    allCosts = [allCosts, customCosts];
                    legends = [legends, {'Customizável'}];
                end
                if app.btnTotalCostGraph.Value
                    totalCosts = [app.bestPerIteration.normalizedTotalCost];
                    allCosts = [allCosts, totalCosts];
                    legends = [legends, {'Custo total'}];
                end
                
                if numel(allCosts) < 1
                    firstBest = 1;
                    lastBest = 0;
                else
                    firstBest = max(allCosts);
                    lastBest = min(allCosts);
                    if firstBest == lastBest
                        firstBest = firstBest + 0.1;
                        lastBest = lastBest - 0.1;
                    else
                        delta = (firstBest - lastBest) / 10;
                        firstBest = firstBest + delta;
                        lastBest = lastBest - delta;
                    end
                end
                
                cla(app.graphResult);
                hold(app.graphResult, 'on');
                numIters = numel(app.bestPerIteration);
                if app.savedGeneralArguments.optimizeConsumption && app.btnConsumptionGraph.Value
                    plot(app.graphResult, 1:numIters, consumptionCosts, 'k:o');
                end
                if app.savedGeneralArguments.optimizeComfort && app.btnComfortGraph.Value
                    plot(app.graphResult, 1:numIters, comfortCosts, 'r:o');
                end
                if app.savedGeneralArguments.optimizeCustom && app.btnCustomGraph.Value
                    plot(app.graphResult, 1:numIters, customCosts, 'g:o');
                end
                if app.btnTotalCostGraph.Value
                    plot(app.graphResult, 1:numIters, totalCosts, 'b-o');
                end
                hold(app.graphResult, 'off');
                
                if numel(legends) > 0
                    legend(app.graphResult, legends);
                else
                    legend(app.graphResult, 'hide');
                end
            end
            axis(app.graphResult, [1 max(2, app.savedGeneralArguments.numIters) lastBest firstBest]);
        end
        
        function executePendingTasks(app)
            drawnow();
        end
        
        function [result, models] = loadDomusModels(app)
            try
                domusFolder = app.eDomusPath.Value;
                if isempty(domusFolder)
                    error('O caminho do Domus não foi especificado!');
                end
                
                materialsFolder = 'Materiais';
                modelsFile = 'Modelos.mtr';
                modelsPath = strcat(domusFolder, filesep(), materialsFolder, filesep(), modelsFile);
                if exist(modelsPath, 'file') ~= 2
                    error('prog:input', 'O arquivo "%s" não foi encontrado no diretório do Domus!', modelsFile);
                end
                
                modelsText = fileread(modelsPath);
                
                [headerText, modelsText] = readBlock(modelsText, '[', ']', false);
                [headerParts] = readProperty(headerText, '=', 2, 'Numero de Modelos');
                numModels = str2double(headerParts{2});
                
                model.name = '';
                model.materials = [];
                model.dimensions = [];
                
                models = repmat(model, 1, numModels);
                
                for i = 1:numModels
                    %Bloco do modelo
                    [entryText, modelsText] = readBlock(modelsText, '{', '}', false);
                    
                    [nameText, entryText] = readBlock(entryText, '[', ']', false);
                    model.name = nameText;
                    
                    [materialsText, entryText] = readBlock(entryText, '[', ']', false);
                    [materialsParts] = readProperty(materialsText, '=', 3, 'Num. Materiais');
                    numMaterials = str2double(materialsParts{2});
                    [materialsText, ~] = readBlock(materialsParts{3}, '{', '}', false);
                    
                    material.id = 0;
                    material.width = 0;
                    material.mesh = 0;
                    
                    model.materials = repmat(material, 0, numMaterials);
                    
                    for j = 1:numMaterials
                        [materialText, materialsText] = readBlock(materialsText, '(', ')', false);
                        [materialParts] = readProperty(materialText, ',', 3, '');
                        
                        material.id = str2double(materialParts{1});
                        material.width = str2double(materialParts{2}) / 10;
                        material.mesh = str2double(materialParts{3});
                        
                        model.materials(j) = material;
                    end
                    
                    [dimensionsText, ~] = readBlock(entryText, '[', ']', false);
                    [dimensionsParts] = readProperty(dimensionsText, '=', 2, '');
                    model.dimensions = dimensionsParts{2};
                    
                    models(i) = model;
                end
                
                result = [];
            catch exception
                result = exception;
                models = [];
            end
        end
        
        function [result, indexes] = loadDomusIndexes(app)
            try
                domusFolder = app.eDomusPath.Value;
                if isempty(domusFolder)
                    error('O caminho do Domus não foi especificado!');
                end
                
                materialsFolder = 'Materiais';
                indexFile = 'indice_BR.mtr';
                indexPath = strcat(domusFolder, filesep(), materialsFolder, filesep(), indexFile);
                if exist(indexPath, 'file') ~= 2
                    error('prog:input', 'O arquivo "%s" não foi encontrado no diretório do Domus!', indexFile);
                end
                
                indexText = fileread(indexPath);
    
                [headerText, indexText] = readBlock(indexText, '[', ']', false);
                [headerParts] = readProperty(headerText, '=', 2, 'Numero de Materiais');
                
                numEntries = str2double(headerParts{2});
                
                index.id = 0;
                index.name = '';
                index.density = 0;
                index.porosity = 0;
                index.specificHeat = 0;
                index.thermalConductivity = 0;
                index.isothermsTable = 0;
                index.isothermsGranularity = 0;
                index.dataFile = '';
                index.isothermsFile = '';
                index.isothermsFunction = 0;
                
                indexes = repmat(index, 1, numEntries);
                
                for i = 1:numEntries
                    [entryText, indexText] = readBlock(indexText, '{', '}', false);
                    
                    [idText, entryText] = readBlock(entryText, '[', ']', false);
                    index.id = str2double(idText);
                    
                    [nameText, entryText] = readBlock(entryText, '[', ']', false);
                    index.name = nameText;
                    
                    [densityText, entryText] = readBlock(entryText, '[', ']', false);
                    [densityParts] = readProperty(densityText, '=', 2, 'Dens_MP');
                    index.density = str2double(densityParts{2});
                    
                    [porosityText, entryText] = readBlock(entryText, '[', ']', false);
                    [porosityParts] = readProperty(porosityText, '=', 2, 'Porosidade');
                    index.porosity = str2double(porosityParts{2});
                    
                    [specificHeatText, entryText] = readBlock(entryText, '[', ']', false);
                    [specificHeatParts] = readProperty(specificHeatText, '=', 2, 'Calor Especifico');
                    index.specificHeat = str2double(specificHeatParts{2});
                    
                    [thermalConductivityText, entryText] = readBlock(entryText, '[', ']', false);
                    [thermalConductivityParts] = readProperty(thermalConductivityText, '=', 2, 'Condu. Termica Cte');
                    index.thermalConductivity = str2double(thermalConductivityParts{2});
                    
                    [isothermsTableText, entryText] = readBlock(entryText, '[', ']', false);
                    [isothermsTableParts] = readProperty(isothermsTableText, '=', 2, 'Tabela Isotermas');
                    index.isothermsTable = str2double(isothermsTableParts{2});
                    
                    [isothermsGranularityText, entryText] = readBlock(entryText, '[', ']', false);
                    [isothermsGranularityParts] = readProperty(isothermsGranularityText, '=', 2, 'Granularidade Isotermas');
                    index.isothermsGranularity = str2double(isothermsGranularityParts{2});
                    
                    [dataFileText, entryText] = readBlock(entryText, '[', ']', false);
                    [dataFileParts] = readProperty(dataFileText, '=', 2, 'Arquivo Dados');
                    index.dataFile = dataFileParts{2};
                    
                    [isothermsFileText, entryText] = readBlock(entryText, '[', ']', false);
                    [isothermsFileParts] = readProperty(isothermsFileText, '=', 2, 'Arquivo Isotermas');
                    index.isothermsFile = isothermsFileParts{2};
                    
                    [isothermsFunctionText, ~] = readBlock(entryText, '[', ']', false);
                    [isothermsFunctionParts] = readProperty(isothermsFunctionText, '=', 2, 'Funcao Isotermas');
                    index.isothermsFunction = isothermsFunctionParts{2};
                    
                    indexes(index.id) = index;
                end
                
                result = [];
            catch exception
                result = exception;
                indexes = [];
            end
        end
    end

    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            assignin('base', 'app', app);
            app.tgAlgorithms.Children(1).Parent = [];
            
            [result] = app.loadConfiguration();
            if isa(result, 'MException')
                errordlg(sprintf('Erro durante o carregamento da configuração: %s', result.message), 'Erro', 'modal');
            end
            
            app.graphResult.PlotBoxAspectRatioMode = 'manual';
            app.graphResult.DataAspectRatioMode = 'auto';
            app.graphResult.PlotBoxAspectRatio = [app.graphResult.Position(3:4) 1];
            app.graphResult.XTickMode = 'auto';
            app.graphResult.YTickMode = 'auto';
        end

        % Button pushed function: btnAddAlgorithm
        function btnAddAlgorithmPushed(app, event)
            algorithmName = app.eAlgorithm.Value;
            if isempty(algorithmName)
                warndlg('Especifique o nome do algoritmo!', 'Nome inválido', 'modal');
                return;
            end
            
            if any(strcmp(app.listAlgorithms.Items, algorithmName))
                warndlg('O algoritmo especificado já existe!', 'Já existente', 'modal');
                return;
            end
            
            [result] = app.addAlgorithm(algorithmName);
            if isa(result, 'MException')
                errordlg(sprintf('Ocorrou um erro durante o carregamento do algoritmo ''%s'': %s', algorithmName, result.message), 'Erro durante carregamento!');
            end
            
            app.eAlgorithm.Value = '';
            app.btnAddAlgorithm.Enable = 'off';
            
            app.saveConfiguration();
        end

        % Button pushed function: btnDomusPathSearch
        function btnDomusPathSearchPushed(app, event)
            path = uigetdir(sprintf('%s%s..', app.eDomusPath.Value, filesep()), 'Diretório do Domus');
            if path == 0
                return;
            end
            
            if exist(sprintf('%s%sDomusConsole.exe', path, filesep()), 'file') ~= 2
               errordlg('O executável DomusConsole.exe não foi encontrado no diretório especificado', 'DomusConsole.exe não encontrado'); 
               return;
            end
            
            app.eDomusPath.Value = path;
            
            app.saveConfiguration();
        end

        % Button pushed function: btnEdificationSearch
        function btnEdificationSearchPushed(app, event)
            [file, path] = uigetfile({'*.idf', 'Edificações'}, 'Arquivo de edificação', app.eEdificationPath.Value);
            if path == 0
                return;
            end
            
            app.eEdificationPath.Value = sprintf('%s%s', path, file);
            
            app.saveConfiguration();
        end

        % Button pushed function: btnIndexMtr
        function btnIndexMtrPushed(app, event)
            varName = app.eIndexMtr.Value;
            if isempty(varName)
                warndlg('Especifique um o nome de uma variável!', 'Especifique um nome', 'modal');
                return;
            end
            
            if ~isvarname(varName)
                warndlg('O nome especificado não é válido!', 'Especifique um nome válido', 'modal');
                return;
            end
            
            [result, indexes] = app.loadDomusIndexes();
            if isa(result, 'MException')
                errordlg(sprintf('Erro ao carregar o arquivo de índices: %s', result.message), 'Erro durante carregamento', 'modal');
                return;
            end
            
            msgbox(sprintf('Os índices foram carregados com sucesso e atribuidos à variável "%s"!', varName), 'Carregamento finalizado', 'help');
            assignin('base', varName, indexes);
        end

        % Button pushed function: btnModelsMtr
        function btnModelsMtrPushed(app, event)
            varName = app.eModelsMtr.Value;
            if isempty(varName)
                warndlg('Especifique uma o nome de uma variável!', 'Especifique um nome', 'modal');
                return;
            end
            
            if ~isvarname(varName)
                warndlg('O nome especificado não é válido!', 'Especifique um nome válido', 'modal');
                return;
            end
            
            [result, models] = app.loadDomusModels();
            if isa(result, 'MException')
                errordlg(sprintf('Erro ao carregar o arquivo de modelos: %s', result.message), 'Erro durante carregamento', 'modal');
                return;
            end
            
            msgbox(sprintf('Os modelos foram carregados com sucesso e atribuidos à variável "%s"!', varName), 'Carregamento finalizado', 'help');
            assignin('base', varName, models);
        end

        % Button pushed function: btnModificationSearch
        function btnModificationSearchPushed(app, event)
            [file, path] = uigetfile({'*.txt', 'Modificações'}, 'Arquivo de modificação', app.eModificationPath.Value);
            if path == 0
                return;
            end
            
            app.eModificationPath.Value = sprintf('%s%s', path, file);
            
            app.saveConfiguration();
        end

        % Button pushed function: btnOptimize
        function btnOptimizePushed(app, event)
            [generalArguments, result] = app.validateGeneralArguments();
            if isa(result, 'MException')
                errordlg(sprintf('Os argumentos gerais estão inconsistentes: %s', result.message), 'Argumentos inválidos', 'modal');
                return;
            end
            
            algorithm = app.tgAlgorithms.SelectedTab.UserData;
            generalArguments.algorithm = algorithm;
            [specificArguments, result] = algorithm.validateArguments(generalArguments);
            if isa(result, 'MException')
                errordlg(sprintf('Os argumentos especificos estão inconsistentes: %s', result.message), 'Argumentos inválidos', 'modal');
                return;
            end
            
            app.savedGeneralArguments = generalArguments;
            app.savedSpecificArguments = specificArguments;
            
            if exist(generalArguments.simulationFolder, 'dir')
                [status, message] = rmdir(generalArguments.simulationFolder, 's');
                if ~status
                    errordlg(sprintf('Erro ao apagar diretório de resultados anteriores: %s', message), 'modal');
                    return;
                end
            end
            
            app.bestPerIteration = [];
            app.solutions = [];
            
            app.eCostEvaluations.Value = 0;
            app.eIterationsPerformed.Value = 0;
            app.updateResultGraph();
            
            app.executePendingTasks();
            
            app.tgMain.SelectedTab = app.tabResults;
            app.toggleAlgorithmDependentFields('off');
            success = 1;
            try
                algorithm.runOptimization(@(x)app.evaluateCostFunction(x), generalArguments, specificArguments);
            catch exception
                errordlg(sprintf('Ocorreu um erro durante o processo de otimização: %s', exception.message), 'Erro na otimização', 'modal');
                success = 0;
            end
            
            if success
                if app.btnStop.Value
                    warndlg('O processo de otimização foi interrompido pelo usuário!', 'Processo interrompido', 'modal');
                else
                    msgbox('O processo de otimização foi concluído com sucesso!', 'Processo finalizado', 'help');
                end
                
            end
            app.toggleAlgorithmDependentFields('on');
        end

        % Button pushed function: btnRemoveAlgorithm
        function btnRemoveAlgorithmPushed(app, event)
            if numel(app.listAlgorithms.Value) == 0
                warndlg('Selecione um ou mais algoritmos para serem removidos', 'Seleção vazia', 'modal');
                return;
            end
            
            removePending = app.listAlgorithms.Value;
            for algorithmName = removePending
                [result] = app.removeAlgorithm(char(algorithmName));
                if isa(result, 'MException')
                    errordlg(sprintf('Ocorreu um erro durante a remoção do algoritmo ''%s'': %s', char(algorithmName), result.message), 'Erro durante remoção!', 'modal');
                    return;
                end
            end
            
            if numel(app.listAlgorithms.Value) == 0
                app.btnRemoveAlgorithm.Enable = 'off';
            end
            
            app.saveConfiguration();
        end

        % Value changing function: eAlgorithm
        function eAlgorithmValueChanging(app, event)
            if isempty(event.Value)
                app.btnAddAlgorithm.Enable = 'off';
            else
                app.btnAddAlgorithm.Enable = 'on';
            end
        end

        % Value changed function: eDomusPath
        function eDomusPathValueChanged(app, event)
            if isempty(pp.eDomusPath.Value)
                app.eModelsMtr.Enable = 'on';
                app.eIndexMtr.Enable = 'on';
                app.btnModelsMtr.Enable = 'on';
                app.btnIndexMtr.Enable = 'on';
            else
                app.eModelsMtr.Enable = 'off';
                app.eIndexMtr.Enable = 'off';
                app.btnModelsMtr.Enable = 'off';
                app.btnIndexMtr.Enable = 'off';
            end
        end

        % Value changed function: listAlgorithms
        function listAlgorithmsValueChanged(app, event)
            if numel(app.listAlgorithms.Value) == 0
                app.btnRemoveAlgorithm.Enable = 'off';
            else
                app.btnRemoveAlgorithm.Enable = 'on';
            end
        end

        % Value changed function: switchComfort
        function switchComfortValueChanged(app, event)
            if strcmp(app.switchComfort.Value, 'Sim')
                app.lampComfort.Color = [0.0 1.0 0.0];
                app.lampComfortMini.Color = [0.0 1.0 0.0];
            else
                app.lampComfort.Color = [0.902 0.902 0.902];
                app.lampComfortMini.Color = [0.902 0.902 0.902];
            end
        end

        % Value changed function: switchConsumption
        function switchConsumptionValueChanged(app, event)
            if strcmp(app.switchConsumption.Value, 'Sim')
                app.lampConsumption.Color = [0.0 1.0 0.0];
                app.lampConsumptionMini.Color = [0.0 1.0 0.0];
            else
                app.lampConsumption.Color = [0.902 0.902 0.902];
                app.lampConsumptionMini.Color = [0.902 0.902 0.902];
            end
        end

        % Value changed function: switchCustom
        function switchCustomValueChanged(app, event)
            if strcmp(app.switchCustom.Value, 'Sim')
                app.lampCustom.Color = [0.0 1.0 0.0];
                app.lampCustomMini.Color = [0.0 1.0 0.0];
            else
                app.lampCustom.Color = [0.902 0.902 0.902];
                app.lampCustomMini.Color = [0.902 0.902 0.902];
            end
        end
    end

    % App initialization and construction
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.BuildingOptimization = uifigure;
            app.BuildingOptimization.Name = 'Otimização de edificações';
            app.tgMain = uitabgroup(app.BuildingOptimization);
            app.tabOptimization = uitab(app.tgMain);
            app.tabOptimization.Title = 'Otimização';
            app.pnlInput = uipanel(app.tabOptimization);
            app.pnlInput.Title = 'Entrada';
            app.LimitesinferioresEditFieldLabel = uilabel(app.pnlInput);
            app.LimitesinferioresEditFieldLabel.Text = 'Limites inferiores';
            app.eLowerLimits = uieditfield(app.pnlInput, 'text');
            app.eLowerLimits.Value = '[0]';
            app.LimitessuperioresEditFieldLabel = uilabel(app.pnlInput);
            app.LimitessuperioresEditFieldLabel.Text = 'Limites superiores';
            app.eUpperLimits = uieditfield(app.pnlInput, 'text');
            app.eUpperLimits.Value = '[0]';
            app.VariveisEditFieldLabel = uilabel(app.pnlInput);
            app.VariveisEditFieldLabel.Text = 'Variáveis';
            app.eNumVars = uieditfield(app.pnlInput, 'numeric');
            app.eNumVars.Limits = [1 99];
            app.eNumVars.RoundFractionalValues = 'on';
            app.eNumVars.ValueDisplayFormat = '%.0f';
            app.eNumVars.Value = 1;
            app.IteraesLabel = uilabel(app.pnlInput);
            app.IteraesLabel.Text = 'Iterações';
            app.eNumIters = uieditfield(app.pnlInput, 'numeric');
            app.eNumIters.Limits = [1 999];
            app.eNumIters.RoundFractionalValues = 'on';
            app.eNumIters.ValueDisplayFormat = '%.0f';
            app.eNumIters.Value = 20;
            app.PopulaoLabel = uilabel(app.pnlInput);
            app.PopulaoLabel.Text = 'População';
            app.eNumPop = uieditfield(app.pnlInput, 'numeric');
            app.eNumPop.Limits = [1 999];
            app.eNumPop.RoundFractionalValues = 'on';
            app.eNumPop.ValueDisplayFormat = '%.0f';
            app.eNumPop.Value = 20;
            app.ArquivoedificaoidfLabel = uilabel(app.pnlInput);
            app.ArquivoedificaoidfLabel.Text = 'Arquivo edificação (.idf)';
            app.eEdificationPath = uieditfield(app.pnlInput, 'text');
            app.eEdificationPath.Editable = 'off';
            app.btnEdificationSearch = uibutton(app.pnlInput, 'push');
            app.btnEdificationSearch.ButtonPushedFcn = createCallbackFcn(app, @btnEdificationSearchPushed, true);
            app.btnEdificationSearch.Text = '...';
            app.ArquivoalteraotxtLabel = uilabel(app.pnlInput);
            app.ArquivoalteraotxtLabel.Text = 'Arquivo alteração (.txt)';
            app.eModificationPath = uieditfield(app.pnlInput, 'text');
            app.eModificationPath.Editable = 'off';
            app.btnModificationSearch = uibutton(app.pnlInput, 'push');
            app.btnModificationSearch.ButtonPushedFcn = createCallbackFcn(app, @btnModificationSearchPushed, true);
            app.btnModificationSearch.Text = '...';
            app.FunodemapeamentoLabel = uilabel(app.pnlInput);
            app.FunodemapeamentoLabel.Text = 'Função de mapeamento';
            app.eMappingFunction = uieditfield(app.pnlInput, 'text');
            app.eMappingFunction.Value = '@(x)x';
            app.tgAlgorithms = uitabgroup(app.pnlInput);
            app.tabPlaceholder = uitab(app.tgAlgorithms);
            app.tabPlaceholder.Title = 'Placeholder';
            app.pnlOutput = uipanel(app.tabOptimization);
            app.pnlOutput.Title = 'Saída';
            app.tgOutput = uitabgroup(app.pnlOutput);
            app.tabConsumption = uitab(app.tgOutput);
            app.tabConsumption.Title = 'Consumo';
            app.lampConsumption = uilamp(app.tabConsumption);
            app.ConsiderarobjetivoLabel_3 = uilabel(app.tabConsumption);
            app.ConsiderarobjetivoLabel_3.Text = 'Considerar objetivo?';
            app.switchConsumption = uiswitch(app.tabConsumption, 'slider');
            app.switchConsumption.Items = {'Não', 'Sim'};
            app.switchConsumption.ValueChangedFcn = createCallbackFcn(app, @switchConsumptionValueChanged, true);
            app.switchConsumption.Value = 'Sim';
            app.PesoEditFieldLabel = uilabel(app.tabConsumption);
            app.PesoEditFieldLabel.Text = 'Peso';
            app.eConsumptionWeight = uieditfield(app.tabConsumption, 'numeric');
            app.eConsumptionWeight.Limits = [0 1];
            app.eConsumptionWeight.ValueDisplayFormat = '%11.5g';
            app.eConsumptionWeight.Value = 1;
            app.ValorbaseEditFieldLabel = uilabel(app.tabConsumption);
            app.ValorbaseEditFieldLabel.Text = 'Valor base';
            app.eConsumptionBase = uieditfield(app.tabConsumption, 'numeric');
            app.eConsumptionBase.Limits = [0 Inf];
            app.DeslocamentoLabel = uilabel(app.tabConsumption);
            app.DeslocamentoLabel.Text = 'Deslocamento';
            app.eConsumptionOffset = uieditfield(app.tabConsumption, 'numeric');
            app.eConsumptionOffset.Limits = [-1 1];
            app.eConsumptionOffset.ValueDisplayFormat = '%11.5g';
            app.tabComfort = uitab(app.tgOutput);
            app.tabComfort.Title = 'Conforto';
            app.lampComfort = uilamp(app.tabComfort);
            app.ConsiderarobjetivoLabel_2 = uilabel(app.tabComfort);
            app.ConsiderarobjetivoLabel_2.Text = 'Considerar objetivo?';
            app.switchComfort = uiswitch(app.tabComfort, 'slider');
            app.switchComfort.Items = {'Não', 'Sim'};
            app.switchComfort.ValueChangedFcn = createCallbackFcn(app, @switchComfortValueChanged, true);
            app.switchComfort.Value = 'Sim';
            app.ValorbaseEditField_2Label = uilabel(app.tabComfort);
            app.ValorbaseEditField_2Label.Text = 'Valor base';
            app.eComfortBase = uieditfield(app.tabComfort, 'numeric');
            app.eComfortBase.Limits = [0 3];
            app.eComfortBase.Editable = 'off';
            app.eComfortBase.Value = 3;
            app.PesoEditField_2Label = uilabel(app.tabComfort);
            app.PesoEditField_2Label.Text = 'Peso';
            app.eComfortWeight = uieditfield(app.tabComfort, 'numeric');
            app.eComfortWeight.Limits = [0 1];
            app.eComfortWeight.ValueDisplayFormat = '%11.5g';
            app.eComfortWeight.Value = 1;
            app.DeslocamentoLabel_2 = uilabel(app.tabComfort);
            app.DeslocamentoLabel_2.Text = 'Deslocamento';
            app.eComfortOffset = uieditfield(app.tabComfort, 'numeric');
            app.eComfortOffset.Limits = [-1 1];
            app.eComfortOffset.ValueDisplayFormat = '%11.5g';
            app.tabCustom = uitab(app.tgOutput);
            app.tabCustom.Title = 'Customizável';
            app.ConsiderarobjetivoLabel = uilabel(app.tabCustom);
            app.ConsiderarobjetivoLabel.Text = 'Considerar objetivo?';
            app.switchCustom = uiswitch(app.tabCustom, 'slider');
            app.switchCustom.Items = {'Não', 'Sim'};
            app.switchCustom.ValueChangedFcn = createCallbackFcn(app, @switchCustomValueChanged, true);
            app.switchCustom.Value = 'Não';
            app.lampCustom = uilamp(app.tabCustom);
            app.ValorbaseEditField_3Label = uilabel(app.tabCustom);
            app.ValorbaseEditField_3Label.Text = 'Valor base';
            app.eCustomBase = uieditfield(app.tabCustom, 'numeric');
            app.eCustomBase.Limits = [0 Inf];
            app.PesoEditField_3Label = uilabel(app.tabCustom);
            app.PesoEditField_3Label.Text = 'Peso';
            app.eCustomWeight = uieditfield(app.tabCustom, 'numeric');
            app.eCustomWeight.Limits = [0 1];
            app.eCustomWeight.ValueDisplayFormat = '%11.5g';
            app.eCustomWeight.Value = 1;
            app.FunoobjetivaEditFieldLabel = uilabel(app.tabCustom);
            app.FunoobjetivaEditFieldLabel.Text = 'Função objetiva';
            app.eCustomFunction = uieditfield(app.tabCustom, 'text');
            app.DeslocamentoLabel_3 = uilabel(app.tabCustom);
            app.DeslocamentoLabel_3.Text = 'Deslocamento';
            app.eCustomOffset = uieditfield(app.tabCustom, 'numeric');
            app.eCustomOffset.Limits = [-1 1];
            app.eCustomOffset.ValueDisplayFormat = '%11.5g';
            app.btnOptimize = uibutton(app.tabOptimization, 'push');
            app.btnOptimize.ButtonPushedFcn = createCallbackFcn(app, @btnOptimizePushed, true);
            app.btnOptimize.Text = 'Otimizar';
            app.lampCustomMini = uilamp(app.tabOptimization);
            app.lampComfortMini = uilamp(app.tabOptimization);
            app.lampConsumptionMini = uilamp(app.tabOptimization);
            app.tabConfiguration = uitab(app.tgMain);
            app.tabConfiguration.Title = 'Configuração';
            app.CaminhosPanel = uipanel(app.tabConfiguration);
            app.CaminhosPanel.Title = 'Caminhos';
            app.btnDomusPathSearch = uibutton(app.CaminhosPanel, 'push');
            app.btnDomusPathSearch.ButtonPushedFcn = createCallbackFcn(app, @btnDomusPathSearchPushed, true);
            app.btnDomusPathSearch.Text = '...';
            app.DiretrioDomusLabel = uilabel(app.CaminhosPanel);
            app.DiretrioDomusLabel.Text = 'Diretório Domus';
            app.eDomusPath = uieditfield(app.CaminhosPanel, 'text');
            app.eDomusPath.ValueChangedFcn = createCallbackFcn(app, @eDomusPathValueChanged, true);
            app.eDomusPath.Editable = 'off';
            app.pnlAlgorithmRegister = uipanel(app.tabConfiguration);
            app.pnlAlgorithmRegister.Title = 'Algoritmos';
            app.listAlgorithms = uilistbox(app.pnlAlgorithmRegister);
            app.listAlgorithms.Items = {};
            app.listAlgorithms.Multiselect = 'on';
            app.listAlgorithms.ValueChangedFcn = createCallbackFcn(app, @listAlgorithmsValueChanged, true);
            app.listAlgorithms.Value = {};
            app.eAlgorithm = uieditfield(app.pnlAlgorithmRegister, 'text');
            app.eAlgorithm.ValueChangingFcn = createCallbackFcn(app, @eAlgorithmValueChanging, true);
            app.btnAddAlgorithm = uibutton(app.pnlAlgorithmRegister, 'push');
            app.btnAddAlgorithm.ButtonPushedFcn = createCallbackFcn(app, @btnAddAlgorithmPushed, true);
            app.btnAddAlgorithm.Enable = 'off';
            app.btnAddAlgorithm.Text = 'Adicionar';
            app.btnRemoveAlgorithm = uibutton(app.pnlAlgorithmRegister, 'push');
            app.btnRemoveAlgorithm.ButtonPushedFcn = createCallbackFcn(app, @btnRemoveAlgorithmPushed, true);
            app.btnRemoveAlgorithm.Enable = 'off';
            app.btnRemoveAlgorithm.Text = 'Remover';
            app.pnlUtils = uipanel(app.tabConfiguration);
            app.pnlUtils.Title = 'Utilitários';
            app.btnModelsMtr = uibutton(app.pnlUtils, 'push');
            app.btnModelsMtr.ButtonPushedFcn = createCallbackFcn(app, @btnModelsMtrPushed, true);
            app.btnModelsMtr.Text = 'Salvar "Modelos.mtr" no Workspace';
            app.btnIndexMtr = uibutton(app.pnlUtils, 'push');
            app.btnIndexMtr.ButtonPushedFcn = createCallbackFcn(app, @btnIndexMtrPushed, true);
            app.btnIndexMtr.Text = 'Salvar "indice_BR.mtr" no Workspace';
            app.VarivelLabel = uilabel(app.pnlUtils);
            app.VarivelLabel.Text = 'Variável';
            app.eModelsMtr = uieditfield(app.pnlUtils, 'text');
            app.VarivelLabel_2 = uilabel(app.pnlUtils);
            app.VarivelLabel_2.Text = 'Variável';
            app.eIndexMtr = uieditfield(app.pnlUtils, 'text');
            app.tabResults = uitab(app.tgMain);
            app.tabResults.Title = 'Resultados';
            app.pnlGraph = uipanel(app.tabResults);
            app.pnlGraph.Title = 'Gráfico';
            app.graphResult = uiaxes(app.pnlGraph);
            title(app.graphResult, 'Melhores valores por iteração')
            xlabel(app.graphResult, 'Iterações')
            ylabel(app.graphResult, 'Custo')
            app.graphResult.XLim = [0 1];
            app.graphResult.XLimMode = 'manual';
            app.graphResult.YLim = [0 1];
            app.graphResult.YLimMode = 'manual';
            app.graphResult.CLim = [0 1];
            app.graphResult.CLimMode = 'manual';
            app.graphResult.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.graphResult.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.pnlOutputControl = uipanel(app.tabResults);
            app.pnlOutputControl.Title = 'Controle';
            app.btnStop = uibutton(app.pnlOutputControl, 'state');
            app.btnStop.Enable = 'off';
            app.btnStop.Text = 'Parar otimização';
            app.pnlOutputGraphs = uipanel(app.pnlOutputControl);
            app.pnlOutputGraphs.Title = 'Exibir gráficos';
            app.btnConsumptionGraph = uibutton(app.pnlOutputGraphs, 'state');
            app.btnConsumptionGraph.Enable = 'off';
            app.btnConsumptionGraph.Text = 'Consumo';
            app.btnComfortGraph = uibutton(app.pnlOutputGraphs, 'state');
            app.btnComfortGraph.Enable = 'off';
            app.btnComfortGraph.Text = 'Conforto';
            app.btnCustomGraph = uibutton(app.pnlOutputGraphs, 'state');
            app.btnCustomGraph.Enable = 'off';
            app.btnCustomGraph.Text = 'Customizável';
            app.btnTotalCostGraph = uibutton(app.pnlOutputGraphs, 'state');
            app.btnTotalCostGraph.Enable = 'off';
            app.btnTotalCostGraph.Text = 'Custo final';
            app.btnTotalCostGraph.Value = true;
            app.VarivelLabel_3 = uilabel(app.pnlOutputControl);
            app.VarivelLabel_3.Text = 'Variável';
            app.eModelsMtr_2 = uieditfield(app.pnlOutputControl, 'text');
            app.btnModelsMtr_2 = uibutton(app.pnlOutputControl, 'push');
            app.btnModelsMtr_2.Text = 'Salvar resultados no Workspace';
            app.pnlStatistics = uipanel(app.tabResults);
            app.pnlStatistics.Title = 'Estatísticas';
            app.IteraesrealizadasLabel = uilabel(app.pnlStatistics);
            app.IteraesrealizadasLabel.Text = 'Iterações realizadas';
            app.eIterationsPerformed = uieditfield(app.pnlStatistics, 'numeric');
            app.eIterationsPerformed.Editable = 'off';
            app.AvaliaesdafunocustoLabel = uilabel(app.pnlStatistics);
            app.AvaliaesdafunocustoLabel.Text = 'Avaliações da função custo';
            app.eCostEvaluations = uieditfield(app.pnlStatistics, 'numeric');
            app.eCostEvaluations.Editable = 'off';
        end
    end

    methods (Access = public)

        % Construct app
        function app = Application

            % Create and configure components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.BuildingOptimization)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.BuildingOptimization)
        end
    end
end